classdef wave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        DampeningLabel                matlab.ui.control.Label
        Dampening                     matlab.ui.control.Slider
        RunButton                     matlab.ui.control.Button
        SimulationTimeEditFieldLabel  matlab.ui.control.Label
        SimulationTimeEditField       matlab.ui.control.NumericEditField
        StopButton                    matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function run_sim(app, K, T, axes)
            Lx = 10;   % Domain length
            Ly = 10;
            dx = 0.1; % grid granularity
            dy = dx;
            nx = fix(Lx/dx); % number of points
            ny = fix(Ly/dy);
            x = linspace(0, Lx, nx);
            y = linspace(0, Ly, ny);
            
            CFL = 0.5;
            c = 1;
            dt = CFL*dx/c;
            
            %% Domain
            h = zeros(nx, ny); % represents field
            h_prev = h;
            h_next = h;
            
            %% Actual simulation
            t=0;
            while(t < T &&  get('SimRunning'))
                % Boundary condition (Reflecting boundary condition)
            %     h(:,[1 ny])=0;
            %     h([1 nx],:)=0;
                
            %     % Absorbing boundary condition
                h_next(1,:)=h(2,:) + (CFL-1)/(CFL+1)*(h_next(2,:)-h(1,:));
                h_next(nx,:)=h(nx-1,:) + (CFL-1)/(CFL+1)*(h_next(nx-1,:)-h(nx,:));
                h_next(:,1)=h(:,2) + (CFL-1)/(CFL+1)*(h_next(:,2)-h(:,1));
                h_next(:,ny)=h(:,ny-1) + (CFL-1)/(CFL+1)*(h_next(:,ny-1)-h(:,ny));
                
                t = t+dt; % stepping time
                h_prev = h;
                h = h_next;
                
                % Source
                if(t < 10)
                    h(50,50) = dt^2*20*sin(30*pi*t/20);
                end
                
                for i=2:nx-1
                    for j=2:ny-1
                        % without dampening
                        h_next(i,j) = 1/(1+K*dt)*(2*h(i,j) - h_prev(i,j) + K*dt*h_prev(i,j) + ...
                            CFL^2*(h(i+1,j)+h(i,j+1)-4*h(i,j)+h(i-1,j)+h(i,j-1)));
                    end
                end
            
                surf(axes,x,y,h,'EdgeColor', 'none');
                axis(axes, [0 Lx 0 Ly -0.05 0.05]);
                pause(0.01);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            K = app.Dampening.Value;
            T = app.SimulationTimeEditField.Value;
            set('SimRunning', true);
            run_sim(app, K, T, app.UIAxes);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            set('SimRunning', false);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DampeningLabel = uilabel(app.UIFigure);
            app.DampeningLabel.Text = 'Dampening';
            app.Dampening = uislider(app.UIFigure);
            app.Dampening.Limits = [0 1];
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SimulationTimeEditFieldLabel = uilabel(app.UIFigure);
            app.SimulationTimeEditFieldLabel.Text = 'Simulation Time';
            app.SimulationTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wave

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end